classdef NVRS_Accounts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_AccountsUIFigure         matlab.ui.Figure
        Panel_4                       matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Panel_2                       matlab.ui.container.Panel
        RefreshButton                 matlab.ui.control.Button
        MainmenuButton                matlab.ui.control.Button
        CreateNewAccountButton        matlab.ui.control.Button
        ChangepasswordButton          matlab.ui.control.Button
        AccountManagementLabel        matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        AccountsLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            user1 = "username";
pass1 = "password";
conn = database('login',user1,pass1);
            

         sqlquery = 'select * from login.dbo.login_db ';
          
            data = select(conn,sqlquery);


            halaman = data.username;
           
            app.TextArea.Value = string(halaman);
        

             movegui(app.NVRS_AccountsUIFigure,'center')

        end

        % Callback function
        function ChangeUsernameButtonPushed(app, event)
            NVRS_ChangeUsername
        end

        % Button pushed function: ChangepasswordButton
        function ChangepasswordButtonPushed(app, event)
            NVRS_ChangePassword
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                    answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
          
        end

        % Button pushed function: CreateNewAccountButton
        function CreateNewAccountButtonPushed(app, event)
            NVRS_CreateNewAccount
        end

        % Close request function: NVRS_AccountsUIFigure
        function NVRS_AccountsUIFigureCloseRequest(app, event)
              YN = uiconfirm(app.NVRS_AccountsUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end

        % Button pushed function: MainmenuButton
        function MainmenuButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
                    user1 = "username";
pass1 = "password";
conn = database('login',user1,pass1);
            

         sqlquery = 'select * from login.dbo.login_db ';
          
            data = select(conn,sqlquery);


            halaman = data.username;
           
            app.TextArea.Value = string(halaman);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_AccountsUIFigure = uifigure('Visible', 'off');
            app.NVRS_AccountsUIFigure.Name = 'NVRS_Accounts';
            app.NVRS_AccountsUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRS_AccountsUIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.NVRS_AccountsUIFigure);
            app.AccountsLabel = uilabel(app.Panel_2);
            app.AccountsLabel.Text = 'Accounts';
            app.TextArea = uitextarea(app.Panel_2);
            app.AccountManagementLabel = uilabel(app.Panel_2);
            app.AccountManagementLabel.Text = '  Account Management';
            app.ChangepasswordButton = uibutton(app.Panel_2, 'push');
            app.ChangepasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepasswordButtonPushed, true);
            app.ChangepasswordButton.Text = 'Change password';
            app.CreateNewAccountButton = uibutton(app.Panel_2, 'push');
            app.CreateNewAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewAccountButtonPushed, true);
            app.CreateNewAccountButton.Text = 'Create New Account';
            app.MainmenuButton = uibutton(app.Panel_2, 'push');
            app.MainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainmenuButtonPushed, true);
            app.MainmenuButton.Text = 'Main menu';
            app.RefreshButton = uibutton(app.Panel_2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'refresh.png');
            app.RefreshButton.Text = 'Refresh';
            app.Panel_4 = uipanel(app.NVRS_AccountsUIFigure);
            app.Image = uiimage(app.Panel_4);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_4);
            app.NonverbalRecognitionSystemLabel.Text = 'Non-verbal Recognition System';
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_4);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.Image_2 = uiimage(app.Panel_4);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.LogoutButton = uibutton(app.Panel_4, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            % Show the figure after all components are created
            app.NVRS_AccountsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_Accounts

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_AccountsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_AccountsUIFigure)
        end
    end
end