classdef NVRS_Capture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_CaptureUIFigure          matlab.ui.Figure
        Button2                       matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        Panel_6                       matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Panel_5                       matlab.ui.container.Panel
        SelectFolderButton            matlab.ui.control.Button
        PreviewCamButton              matlab.ui.control.Button
        FolderNameEditField           matlab.ui.control.EditField
        FolderNameEditFieldLabel      matlab.ui.control.Label
        Image4                        matlab.ui.control.Image
        NumberofImagesEditField       matlab.ui.control.NumericEditField
        NumberofImagesEditFieldLabel  matlab.ui.control.Label
        CaptureImagesButton           matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        MainMenuButton_2              matlab.ui.control.Button
        Image3                        matlab.ui.control.Image
        FileNameLabel                 matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        BrowseImageButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        DetectButton                  matlab.ui.control.Button
        TrainButton                   matlab.ui.control.Button
        MainButton                    matlab.ui.control.Button
        SearchButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
 webcamObject;
 imageObject;
 KeepRunning;
    end
    
    properties (Access = public)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              cd(fileparts(which('NVRS_Capture.mlapp')));

              app.UIAxes.Visible =false;
                app.UIAxes_2.Visible =false;

                 movegui(app.NVRS_CaptureUIFigure,'center')
        end

        % Button pushed function: CaptureImagesButton
        function CaptureImagesButtonPushed(app, event)
halaman = false;
            app.UIAxes_2.Visible = true;
app.Image4.Visible = false;
            
  x=500;
y=250;
height=1100;
width=650;
bboxes=[x y height width];
count = 1;
totake = app.NumberofImagesEditField.Value;
c = webcam;
while count <= totake
     e=c.snapshot;
     pause(1.0)
    IFaces = insertObjectAnnotation(e,'rectangle',bboxes,'Processing Area');   
imshow(IFaces,'parent',app.UIAxes_2,...
    'XData', [1 app.UIAxes_2.Position(3)], ...
    'YData', [1 app.UIAxes_2.Position(4)]);

filename=strcat(num2str(count),'.bmp');
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
    imwrite(es,filename);
    count=count+1;
    drawnow;
    
end
msgbox('Images Captured')
    app.PreviewCamButton.Visible = true;
           
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            app.UIAxes.Visible = true;
            app.Image3.Visible = false;
            
            cd(fileparts(which('NVRS_Capture.mlapp')));
            app.UIAxes.Visible = true;
            
            [filename, pathname] = uigetfile({'.bmp'},"Open file");
            fullpathname = strcat(pathname,filename);
            img = imread(fullpathname);
            imshow(img,'parent', app.UIAxes);

            
            app.FileNameEditField.Value = filename;
            app.UIAxes_2.Visible = false;
            app.FileNameEditField.Visible=true;
            app.FileNameLabel.Visible=true;
        end

        % Close request function: NVRS_CaptureUIFigure
        function NVRS_CaptureUIFigureCloseRequest(app, event)
                 YN = uiconfirm(app.NVRS_CaptureUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.delete
            NVRS_Search
        end

        % Callback function
        function ButtonPushed2(app, event)
            
        end

        % Callback function
        function CropButtonPushed(app, event)
read = imread(app.FileNameEditField.Value);
global crop
crop = imcrop(read);
imshow(crop,'parent',app.UIAxes)
app.CropButton.Visible = false;
app.SaveButton.Visible= true;


        end

        % Callback function
        function SliderValueChanged(app, event)

            
        end

        % Callback function
        function SliderValueChanging(app, event)

     
        end

        % Callback function
        function Slider_2ValueChanging(app, event)
             
            
        end

        % Callback function
        function SaveButtonPushed(app, event)
global crop
            imwrite(crop,'sadad.bmp')
msgbox("Operation Completed");
               
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.delete
            NVRS_Train
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Callback function
        function ButtonPushed3(app, event)
                  str = sprintf('sadasdasdas');
            uisuccess(app.NVRS_CaptureUIFigure, str, 'Success');
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            
        end

        % Callback function
        function ButtonPushed4(app, event)
            app.UIAxes_2.Visible=false;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
                  cd(fileparts(which('NVRS_Capture.mlapp')));
            dname = uigetdir("fileparts(which('NVRS_Capture.mlapp'))");
            [~,name] = fileparts(dname);
            app.FolderNameEditField.Value = name;
          cd(dname)
        end

        % Callback function
        function MainMenuButtonPushed(app, event)
            app.delete
            NVRS_Main

        end

        % Button pushed function: MainMenuButton_2
        function MainMenuButton_2Pushed(app, event)
            app.delete
            NVRS_Main
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: PreviewCamButton
        function PreviewCamButtonPushed(app, event)
            try
                app.PreviewCamButton.Visible=false;
            app.UIAxes_2.Visible = true;
app.Image4.Visible = false;
c= webcam;
x=500;
y=250;
height=1100;
width=650;
bboxes=[x y height width];
halaman =true;
while halaman
    e=c.snapshot;
               IFaces = insertObjectAnnotation(e,'rectangle',bboxes,'Processing Area');   
imshow(IFaces,'parent',app.UIAxes_2,...
    'XData', [1 app.UIAxes_2.Position(3)], ...
    'YData', [1 app.UIAxes_2.Position(4)]);
pause(0.00000000000000001)
end
            catch
            end
        end

        % Callback function
        function ButtonPushed5(app, event)
                    answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_Login
        
    case 'No'
       
   
end
          
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
              answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_CaptureUIFigure = uifigure('Visible', 'off');
            app.NVRS_CaptureUIFigure.Name = 'NVRS_Capture';
            app.NVRS_CaptureUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRS_CaptureUIFigureCloseRequest, true);
            app.Panel_3 = uipanel(app.NVRS_CaptureUIFigure);
            app.SearchButton = uibutton(app.Panel_3, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Icon = 'icons8-search-64 (1).png';
            app.SearchButton.Text = 'Search';
            app.MainButton = uibutton(app.Panel_3, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Icon = 'icons8-menu-64.png';
            app.MainButton.Text = 'Main';
            app.TrainButton = uibutton(app.Panel_3, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Icon = 'icons8-training-64 (1).png';
            app.TrainButton.Text = 'Train';
            app.DetectButton = uibutton(app.Panel_3, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Icon = 'icons8-mayura-gesture-64.png';
            app.DetectButton.Text = 'Detect';
            app.Panel_4 = uipanel(app.NVRS_CaptureUIFigure);
            app.UIAxes = uiaxes(app.Panel_4);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseImageButton = uibutton(app.Panel_4, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Icon = 'icons8-browse-64.png';
            app.BrowseImageButton.Text = 'Browse Image';
            app.FileNameEditField = uieditfield(app.Panel_4, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameLabel = uilabel(app.Panel_4);
            app.FileNameLabel.Text = 'File Name:';
            app.Image3 = uiimage(app.Panel_4);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'avatar-1577909_1280.jpeg');
            app.MainMenuButton_2 = uibutton(app.Panel_4, 'push');
            app.MainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_2Pushed, true);
            app.MainMenuButton_2.Text = 'Main Menu';
            app.Panel_5 = uipanel(app.NVRS_CaptureUIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_5);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.CaptureImagesButton = uibutton(app.Panel_5, 'push');
            app.CaptureImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImagesButtonPushed, true);
            app.CaptureImagesButton.Icon = 'icons8-unsplash-50.png';
            app.CaptureImagesButton.Text = 'Capture Images';
            app.NumberofImagesEditFieldLabel = uilabel(app.Panel_5);
            app.NumberofImagesEditFieldLabel.Text = 'Number of Images';
            app.NumberofImagesEditField = uieditfield(app.Panel_5, 'numeric');
            app.Image4 = uiimage(app.Panel_5);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'avatar-1577909_1280.jpeg');
            app.FolderNameEditFieldLabel = uilabel(app.Panel_5);
            app.FolderNameEditFieldLabel.Text = 'Folder Name:';
            app.FolderNameEditField = uieditfield(app.Panel_5, 'text');
            app.PreviewCamButton = uibutton(app.Panel_5, 'push');
            app.PreviewCamButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewCamButtonPushed, true);
            app.PreviewCamButton.Text = 'Preview Cam';
            app.SelectFolderButton = uibutton(app.Panel_5, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.Panel_6 = uipanel(app.NVRS_CaptureUIFigure);
            app.Image = uiimage(app.Panel_6);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_6);
            app.NonverbalRecognitionSystemLabel.Text = 'Non-verbal Recognition System';
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_6);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.Image_2 = uiimage(app.Panel_6);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.LogoutButton = uibutton(app.Panel_6, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.EditField = uieditfield(app.NVRS_CaptureUIFigure, 'text');
            app.Button2 = uibutton(app.NVRS_CaptureUIFigure, 'push');
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.NVRS_CaptureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_Capture

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_CaptureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_CaptureUIFigure)
        end
    end
end