classdef NVRS_ChangePassword < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangepasswordUIFigure          matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        ConfirmPasswordEditField        matlab.ui.control.EditField
        ConfirmPasswordEditFieldLabel   matlab.ui.control.Label
        NewPasswordEditField            matlab.ui.control.EditField
        NewPasswordEditFieldLabel       matlab.ui.control.Label
        ChangePasswordLabel             matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        NonVerbalRecognitionSytemLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
               movegui(app.ChangepasswordUIFigure,'center')

               app.Button_3.Visible = false;
       

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
      newpass = app.NewPasswordEditField.Value;
newpassconfirm = app.ConfirmPasswordEditField.Value;
username = app.UsernameEditField.Value;
            
     
user1 = "username";
            pass1 = "password";
                conn = database('login',user1,pass1);

                whereclause = [ ' WHERE username =  ''',username,''''] ;


             if isempty(username)
             msgbox('Username cannot be empty!')
             elseif   isempty(newpass) && isempty(newpassconfirm)
                msgbox('Password Expected')


            elseif   isempty(newpass)
                msgbox('New Password Expected')
                     elseif   isempty(newpassconfirm)
                         msgbox('Please confirm password')
                         elseif strcmpi(newpass,newpassconfirm)


                update(conn,'login_db',{'password'}, {newpassconfirm}, whereclause )
     msgbox('New password Set!')
     app.delete
            else
                msgbox('Password does not match')
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Visible = false;
            app.Button_3.Visible= true;
            app.NewPasswordEditField.FontColor = 'black';
app.ConfirmPasswordEditField.FontColor='black';
        end

        % Callback function: not associated with a component
        function Button_2Pushed(app, event)
          
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Visible=false;
            app.Button.Visible=true;
            app.NewPasswordEditField.FontColor = 'white';
            app.ConfirmPasswordEditField.FontColor='white';

        end

        % Callback function: not associated with a component
        function Button_4Pushed(app, event)
            app.Button_4.Visible= false;
            app.Button_2.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ChangepasswordUIFigure = uifigure('Visible', 'off');
            app.ChangepasswordUIFigure.Name = 'Change password';
            app.Image = uiimage(app.ChangepasswordUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '11-removebg-preview.png');
            app.NonVerbalRecognitionSytemLabel = uilabel(app.ChangepasswordUIFigure);
            app.NonVerbalRecognitionSytemLabel.Text = {'Non-Verbal '; 'Recognition'; ' Sytem'};
            app.Panel = uipanel(app.ChangepasswordUIFigure);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.Panel, 'text');
            app.ChangePasswordLabel = uilabel(app.Panel);
            app.ChangePasswordLabel.Text = 'Change Password';
            app.NewPasswordEditFieldLabel = uilabel(app.Panel);
            app.NewPasswordEditFieldLabel.Text = 'New Password';
            app.NewPasswordEditField = uieditfield(app.Panel, 'text');
            app.ConfirmPasswordEditFieldLabel = uilabel(app.Panel);
            app.ConfirmPasswordEditFieldLabel.Text = 'Confirm Password';
            app.ConfirmPasswordEditField = uieditfield(app.Panel, 'text');
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'eye.png');
            app.Button_3.Text = '';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'photo.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.ChangepasswordUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_ChangePassword

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangepasswordUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangepasswordUIFigure)
        end
    end
end