classdef NVRS_CreateNewAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Create                          matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CreateNewAccountLabel           matlab.ui.control.Label
        AccountlevelDropDown            matlab.ui.control.DropDown
        AccountlevelDropDownLabel       matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        CreateNewAccountButton          matlab.ui.control.Button
        NonVerbalRecognitionSytemLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Create,'center')
            app.Button_2.Visible=false;
        end

        % Button pushed function: CreateNewAccountButton
        function CreateNewAccountButtonPushed(app, event)
            user1 = "username";
pass1 = "password";
conn = database('login',user1,pass1);

 value = app.AccountlevelDropDown.Value;
 if strcmpi(value,'Admin')
                acclvl = 'admin';
 elseif strcmpi(value,'User')
               acclvl = 'user';
 end
username = app.UsernameEditField.Value;
 password = app.PasswordEditField.Value ;

 halaman = table(string(acclvl),string(username),string(password),....
     'VariableNames',{'accountlevel' 'username' 'password'});
tablename = 'login.dbo.login_db';
       sqlwrite(conn,tablename,halaman)
msgbox('account created')
app.delete
        end

        % Value changed function: AccountlevelDropDown
        function AccountlevelDropDownValueChanged(app, event)
           
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button_2.Visible = true;
            app.Button.Visible = false;
            app.PasswordEditField.FontColor = 'black';
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             app.Button_2.Visible = false;
            app.Button.Visible = true;
            app.PasswordEditField.FontColor = 'white';
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Create = uifigure('Visible', 'off');
            app.Create.Name = 'Create new account';
            app.Image = uiimage(app.Create);
            app.Image.ImageSource = fullfile(pathToMLAPP, '11-removebg-preview.png');
            app.NonVerbalRecognitionSytemLabel = uilabel(app.Create);
            app.NonVerbalRecognitionSytemLabel.Text = {'Non-Verbal '; 'Recognition'; ' Sytem'};
            app.Panel = uipanel(app.Create);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.CreateNewAccountButton = uibutton(app.Panel, 'push');
            app.CreateNewAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewAccountButtonPushed, true);
            app.CreateNewAccountButton.Text = 'Create New Account';
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.Panel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Panel, 'text');
            app.AccountlevelDropDownLabel = uilabel(app.Panel);
            app.AccountlevelDropDownLabel.Text = 'Account level';
            app.AccountlevelDropDown = uidropdown(app.Panel);
            app.AccountlevelDropDown.Items = {'Admin', 'User'};
            app.AccountlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @AccountlevelDropDownValueChanged, true);
            app.AccountlevelDropDown.Value = 'Admin';
            app.CreateNewAccountLabel = uilabel(app.Panel);
            app.CreateNewAccountLabel.Text = 'Create New Account';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'photo.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'eye.png');
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.Create.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_CreateNewAccount

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Create)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Create)
        end
    end
end