classdef NVRS_Detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_DetectUIFigure           matlab.ui.Figure
        SentenceformedLabel           matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Panel_8                       matlab.ui.container.Panel
        ScoreTextArea                 matlab.ui.control.TextArea
        ScoreLabel                    matlab.ui.control.Label
        DetectedTextTextArea          matlab.ui.control.TextArea
        DetectedTextLabel             matlab.ui.control.Label
        ChoosewhattodotodetectedgesturesLabel  matlab.ui.control.Label
        SpaceButton                   matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        Panel_6                       matlab.ui.container.Panel
        MainMenuButton_2              matlab.ui.control.Button
        GotoDetectButton              matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        DetectDropDown                matlab.ui.control.DropDown
        DetectDropDownLabel           matlab.ui.control.Label
        ContinueButton                matlab.ui.control.Button
        ChoosewhattodetectLabel       matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        webcamObject;
        imageObject;
        % Description
    end
    
    properties (Access = public)
        Property3 % Description
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Visible = false;
            app.UIAxes.Visible = false;
              movegui(app.NVRS_DetectUIFigure,'center')

        end

        % Callback function
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: NVRS_DetectUIFigure
        function NVRS_DetectUIFigureCloseRequest(app, event)
                  YN = uiconfirm(app.NVRS_DetectUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
            
        end

        % Callback function
        function SearchButtonPushed(app, event)
            app.delete
            NVRS_Search
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
     dttext = '';
            app.TextArea.Value = dttext;
       msgbox("Operation Completed. SUCCESSFULLY DELETED");
        end

        % Callback function
        function ButtonPushed(app, event)
             str = app.TextArea.Value;
    dttext = convertCharsToStrings(app.DetectedTextTextArea.Value);
    newStr = [str,dttext];

    app.TextArea.Value =newStr;
       msgbox("Operation Completed");
        end

        % Callback function
        function STOPButtonPushed2(app, event)
            
        end

        % Callback function
        function TrainButtonPushed(app, event)
            app.delete
            NVRS_Train
        end

        % Callback function
        function STOPButtonPushed3(app, event)
                
        end

        % Callback function
        function WordsButtonPushed(app, event)

        end

        % Callback function
        function CaptureButtonPushed(app, event)
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed2(app, event)

            dttext = convertCharsToStrings(app.DetectedTextTextArea.Value);
  app.TextArea.Value = app.TextArea.Value + dttext;
        end

        % Callback function
        function RESUMEButtonPushed(app, event)
            global whileloop 
            whileloop = true;
            app.RESUMEButton.Visible=false;
            app.STOPButton.Visible=true;
            msgbox("Operation Completed. RESUMED");
        end

        % Button pushed function: SpaceButton
        function SpaceButtonPushed(app, event)
            tinapay = app.TextArea.Value + " ";
            app.TextArea.Value = tinapay;
        end

        % Callback function
        function DetectButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Callback function
        function NumbersButtonPushed(app, event)
            
        end

        % Callback function
        function addddddButtonValueChanged(app, event)
    
            
        end

        % Callback function
        function MainButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Callback function
        function CustomButtonPushed(app, event)
               
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.UIAxes.Visible = true;
            app.Image3.Visible = false;
            app.ContinueButton.Visible = false;
            app.StopButton.Visible = true;
            value = app.DetectDropDown.Value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%words%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmpi(value,'Words')
  x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;
    load ('Words_Images.mat','Words_Images');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
label=classify(Words_Images,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
pause(0.1)

end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%letters%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            elseif strcmpi(value,'Letters')
  x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;
load ('ToTrainLetters.mat','ToTrainLetters');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
[label,score]=classify(ToTrainLetters,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
app.ScoreTextArea.Value = string(score);
pause(0.1)

end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%numbers%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            elseif strcmpi(value,'Numbers')
    x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;
    load ('ToTrainNumbers.mat','ToTrainNumbers');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
[label,score]=classify(ToTrainNumbers,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
app.ScoreTextArea.Value = string(score);
pause(0.1)
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%custom%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else 
  x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;

load ('customtrained.mat','customtrained');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
[label,score]=classify(customtrained,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
app.ScoreTextArea.Value = string(score);
pause(0.1)
end
            end
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Image3.Visible=true;
            app.UIAxes.Visible=false;
            app.StopButton.Visible = false;
            app.ContinueButton.Visible = true;
            global whileloop
            whileloop = false;
              msgbox("Operation Completed. STOP");
        end

        % Callback function
        function MainMenuButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Button pushed function: GotoDetectButton
        function GotoDetectButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Button pushed function: MainMenuButton_2
        function MainMenuButton_2Pushed(app, event)
            app.delete
            NVRS_Main
        end

        % Callback function
        function SearchGesturesButtonPushed(app, event)
            app.delete
            NVRS_Search
        end

        % Callback function
        function TrainGesturesButtonPushed(app, event)
           app.delete
           NVRS_Train
        end

        % Callback function
        function CaptureImagesButtonPushed(app, event)
            app.delete
            NVRS_Capture
        end

        % Callback function
        function ButtonPushed2(app, event)
                                               x=100;

        end

        % Callback function
        function ButtonPushed3(app, event)
                  answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_Login
        
    case 'No'
       
   
end
          
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                     answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_DetectUIFigure = uifigure('Visible', 'off');
            app.NVRS_DetectUIFigure.Name = 'NVRS_Detect';
            app.NVRS_DetectUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRS_DetectUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.NVRS_DetectUIFigure);
            app.UIAxes.ALim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.Panel_5 = uipanel(app.NVRS_DetectUIFigure);
            app.Image = uiimage(app.Panel_5);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.Image2 = uiimage(app.Panel_5);
            app.Image2.ImageSource = 'coeng.png';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_5);
            app.NonverbalRecognitionSystemLabel.Text = 'Non-verbal Recognition System';
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_5);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.LogoutButton = uibutton(app.Panel_5, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.Panel_6 = uipanel(app.NVRS_DetectUIFigure);
            app.ChoosewhattodetectLabel = uilabel(app.Panel_6);
            app.ChoosewhattodetectLabel.Text = 'Choose what to detect';
            app.ContinueButton = uibutton(app.Panel_6, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.DetectDropDownLabel = uilabel(app.Panel_6);
            app.DetectDropDownLabel.Text = 'Detect';
            app.DetectDropDown = uidropdown(app.Panel_6);
            app.DetectDropDown.Items = {'Words', 'Letters', 'Numbers', 'Custom trained'};
            app.DetectDropDown.Value = 'Words';
            app.StopButton = uibutton(app.Panel_6, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GotoDetectButton = uibutton(app.Panel_6, 'push');
            app.GotoDetectButton.ButtonPushedFcn = createCallbackFcn(app, @GotoDetectButtonPushed, true);
            app.GotoDetectButton.Text = 'Go to Detect';
            app.MainMenuButton_2 = uibutton(app.Panel_6, 'push');
            app.MainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_2Pushed, true);
            app.MainMenuButton_2.Text = 'Main Menu';
            app.TextArea = uitextarea(app.NVRS_DetectUIFigure);
            app.Panel_8 = uipanel(app.NVRS_DetectUIFigure);
            app.AddButton = uibutton(app.Panel_8, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed2, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.Panel_8, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SpaceButton = uibutton(app.Panel_8, 'push');
            app.SpaceButton.ButtonPushedFcn = createCallbackFcn(app, @SpaceButtonPushed, true);
            app.SpaceButton.Text = 'Space';
            app.ChoosewhattodotodetectedgesturesLabel = uilabel(app.Panel_8);
            app.ChoosewhattodotodetectedgesturesLabel.Text = 'Choose what to do to detected gestures';
            app.DetectedTextLabel = uilabel(app.Panel_8);
            app.DetectedTextLabel.Text = 'Detected Text:';
            app.DetectedTextTextArea = uitextarea(app.Panel_8);
            app.ScoreLabel = uilabel(app.Panel_8);
            app.ScoreLabel.Text = 'Score:';
            app.ScoreTextArea = uitextarea(app.Panel_8);
            app.Image3 = uiimage(app.NVRS_DetectUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'avatar-1577909_1280.jpeg');
            app.SentenceformedLabel = uilabel(app.NVRS_DetectUIFigure);
            app.SentenceformedLabel.Text = 'Sentence formed';
            % Show the figure after all components are created
            app.NVRS_DetectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_Detect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_DetectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_DetectUIFigure)
        end
    end
end