classdef NVRS_DetectMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NonVerbalRecognitionSystemDetectUIUIFigure  matlab.ui.Figure
        NurturingTomorrowsNoblestLabel  matlab.ui.control.Label
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Panel_6                         matlab.ui.container.Panel
        Panel_5                         matlab.ui.container.Panel
        GotoSentenceFormationButton     matlab.ui.control.Button
        DetectDropDown                  matlab.ui.control.DropDown
        DetectDropDownLabel             matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        MainMenuButton_2                matlab.ui.control.Button
        DetectGesturesLabel             matlab.ui.control.Label
        LogoutButton                    matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Panel_4                         matlab.ui.container.Panel
        CustomButton                    matlab.ui.control.Button
        SentenceFormationButton         matlab.ui.control.Button
        WordsButton                     matlab.ui.control.Button
        NumbersButton                   matlab.ui.control.Button
        LettersButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
 webcamObject;
 imageObject;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            movegui(app.NonVerbalRecognitionSystemDetectUIUIFigure,'center')
        end

        % Button pushed function: SentenceFormationButton
        function SentenceFormationButtonPushed(app, event)
            app.delete
            NVRS_Detect
        end

        % Button pushed function: LettersButton
        function LettersButtonPushed(app, event)
app.delete
            NVRS_Letters
        end

        % Button pushed function: WordsButton
        function WordsButtonPushed(app, event)
            app.delete
            NVRS_Words
        end

        % Button pushed function: NumbersButton
        function NumbersButtonPushed(app, event)
            app.delete
            NVRS_Numbers
        end

        % Close request function: 
        % NonVerbalRecognitionSystemDetectUIUIFigure
        function NonVerbalRecognitionSystemDetectUIUIFigureCloseRequest(app, event)
            YN = uiconfirm(app.NonVerbalRecognitionSystemDetectUIUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end

        % Callback function
        function ButtonPushed(app, event)
            clear
        end

        % Callback function
        function CaptureButtonPushed(app, event)
           app.delete
            NVRS_Capture
        end

        % Button pushed function: CustomButton
        function CustomButtonPushed(app, event)
             app.delete
            NVRS_Custom
        end

        % Callback function
        function MainButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Callback function
        function MainMenuButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            value = app.DetectDropDown.Value;
            if strcmpi(value,'Words')
                app.delete
                NVRS_Words
            elseif strcmpi(value,'Letters')
                app.delete
                NVRS_Letters
            elseif strcmpi(value,'Numbers')
                app.delete
                NVRS_Numbers
            else 
                app.delete
                NVRS_Custom
            end
                
        end

        % Value changed function: DetectDropDown
        function DetectDropDownValueChanged(app, event)
            value = app.DetectDropDown.Value;
            
        end

        % Button pushed function: GotoSentenceFormationButton
        function GotoSentenceFormationButtonPushed(app, event)
            app.delete
            NVRS_Detect
        end

        % Button pushed function: MainMenuButton_2
        function MainMenuButton_2Pushed(app, event)
            app.delete
            NVRS_Main
        end

        % Callback function
        function ButtonPushed2(app, event)
                  answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_Login
        
    case 'No'
       
   
end
          
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                    answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NonVerbalRecognitionSystemDetectUIUIFigure = uifigure('Visible', 'off');
            app.NonVerbalRecognitionSystemDetectUIUIFigure.Name = 'Non-Verbal Recognition System Detect UI';
            app.NonVerbalRecognitionSystemDetectUIUIFigure.Icon = fullfile(pathToMLAPP, 'Nvrs.png');
            app.NonVerbalRecognitionSystemDetectUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @NonVerbalRecognitionSystemDetectUIUIFigureCloseRequest, true);
            app.Panel_4 = uipanel(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.LettersButton = uibutton(app.Panel_4, 'push');
            app.LettersButton.ButtonPushedFcn = createCallbackFcn(app, @LettersButtonPushed, true);
            app.LettersButton.Icon = 'icons8-abc-64.png';
            app.LettersButton.Text = 'Letters';
            app.NumbersButton = uibutton(app.Panel_4, 'push');
            app.NumbersButton.ButtonPushedFcn = createCallbackFcn(app, @NumbersButtonPushed, true);
            app.NumbersButton.Icon = 'icons8-cube-numbers-64.png';
            app.NumbersButton.Text = 'Numbers';
            app.WordsButton = uibutton(app.Panel_4, 'push');
            app.WordsButton.ButtonPushedFcn = createCallbackFcn(app, @WordsButtonPushed, true);
            app.WordsButton.Icon = 'icons8-scrabble-64.png';
            app.WordsButton.Text = 'Words';
            app.SentenceFormationButton = uibutton(app.Panel_4, 'push');
            app.SentenceFormationButton.ButtonPushedFcn = createCallbackFcn(app, @SentenceFormationButtonPushed, true);
            app.SentenceFormationButton.Text = {'Sentence Formation'; ''};
            app.CustomButton = uibutton(app.Panel_4, 'push');
            app.CustomButton.ButtonPushedFcn = createCallbackFcn(app, @CustomButtonPushed, true);
            app.CustomButton.Icon = 'icons8-random-64.png';
            app.CustomButton.Text = 'Custom';
            app.Image2 = uiimage(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.Image2.ImageSource = 'coeng.png';
            app.Panel_5 = uipanel(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.LogoutButton = uibutton(app.Panel_5, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.DetectGesturesLabel = uilabel(app.Panel_5);
            app.DetectGesturesLabel.Text = 'Detect Gestures';
            app.MainMenuButton_2 = uibutton(app.Panel_5, 'push');
            app.MainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_2Pushed, true);
            app.MainMenuButton_2.Text = 'Main Menu';
            app.ContinueButton = uibutton(app.Panel_5, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.DetectDropDownLabel = uilabel(app.Panel_5);
            app.DetectDropDownLabel.Text = 'Detect';
            app.DetectDropDown = uidropdown(app.Panel_5);
            app.DetectDropDown.Items = {'Words', 'Letters', 'Numbers', 'Custom trained'};
            app.DetectDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectDropDownValueChanged, true);
            app.DetectDropDown.Value = 'Words';
            app.GotoSentenceFormationButton = uibutton(app.Panel_5, 'push');
            app.GotoSentenceFormationButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSentenceFormationButtonPushed, true);
            app.GotoSentenceFormationButton.Text = 'Go to Sentence Formation';
            app.Panel_6 = uipanel(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.Image3 = uiimage(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '331413091_577097417633473_8017065334155093836_n.jpg');
            app.Image = uiimage(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.NonverbalRecognitionSystemLabel = uilabel(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.NonverbalRecognitionSystemLabel.Text = {'Non-verbal'; 'Recognition System'};
            app.NurturingTomorrowsNoblestLabel = uilabel(app.NonVerbalRecognitionSystemDetectUIUIFigure);
            app.NurturingTomorrowsNoblestLabel.Text = '"Nurturing Tomorrow''s Noblest"';
            % Show the figure after all components are created
            app.NonVerbalRecognitionSystemDetectUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_DetectMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NonVerbalRecognitionSystemDetectUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NonVerbalRecognitionSystemDetectUIUIFigure)
        end
    end
end