classdef NVRS_DetectMainforUser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRSDetectUIFigure              matlab.ui.Figure
        NurturingTomorrowsNoblestLabel  matlab.ui.control.Label
        NonverbalRecognitionSystemLabel_2  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Panel_4                         matlab.ui.container.Panel
        GotoSentenceFormationButton_2   matlab.ui.control.Button
        DetectDropDown_2                matlab.ui.control.DropDown
        DetectDropDown_2Label           matlab.ui.control.Label
        ContinueButton_2                matlab.ui.control.Button
        MainMenuButton_2                matlab.ui.control.Button
        DetectGesturesLabel             matlab.ui.control.Label
        LogoutButton                    matlab.ui.control.Button
        Image2_2                        matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        CustomButton_2                  matlab.ui.control.Button
        SentenceFormationButton_2       matlab.ui.control.Button
        WordsButton_2                   matlab.ui.control.Button
        NumbersButton_2                 matlab.ui.control.Button
        LettersButton_2                 matlab.ui.control.Button
        DetectDropDown                  matlab.ui.control.DropDown
        DetectDropDownLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SearchGesturesButton            matlab.ui.control.Button
        MainMenuButton                  matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        UniversityofRizalSystemLabel    matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GotoSentenceFormationButton     matlab.ui.control.Button
        ContinueButton                  matlab.ui.control.Button
        ChoosewhattodetectLabel         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CustomButton                    matlab.ui.control.Button
        SentenceFormationButton         matlab.ui.control.Button
        WordsButton                     matlab.ui.control.Button
        NumbersButton                   matlab.ui.control.Button
        LettersButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     movegui(app.NVRSDetectUIFigure,'center')
        end

        % Button pushed function: ContinueButton_2
        function ContinueButton_2Pushed(app, event)
             value = app.DetectDropDown_2.Value;
            if strcmpi(value,'Words')
                app.delete
                NVRS_Words
            elseif strcmpi(value,'Letters')
                app.delete
                NVRS_Letters
            elseif strcmpi(value,'Numbers')
                app.delete
                NVRS_Numbers
            else 
                app.delete
                NVRS_Custom
            end
                
        end

        % Button pushed function: MainMenuButton_2
        function MainMenuButton_2Pushed(app, event)
            app.delete
            NVRS_MainforUser
        end

        % Button pushed function: GotoSentenceFormationButton_2
        function GotoSentenceFormationButton_2Pushed(app, event)
            app.delete
            NVRS_DetectforUser
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                                answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
          
          
        end

        % Close request function: NVRSDetectUIFigure
        function NVRSDetectUIFigureCloseRequest(app, event)
             YN = uiconfirm(app.NVRSDetectUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
            
        end

        % Value changed function: DetectDropDown_2
        function DetectDropDown_2ValueChanged(app, event)
            value = app.DetectDropDown_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRSDetectUIFigure = uifigure('Visible', 'off');
            app.NVRSDetectUIFigure.Name = 'NVRS Detect ';
            app.NVRSDetectUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRSDetectUIFigureCloseRequest, true);
            app.Panel = uipanel(app.NVRSDetectUIFigure);
            app.LettersButton = uibutton(app.Panel, 'push');
            app.LettersButton.Icon = 'icons8-abc-64.png';
            app.LettersButton.Text = 'Letters';
            app.NumbersButton = uibutton(app.Panel, 'push');
            app.NumbersButton.Icon = 'icons8-cube-numbers-64.png';
            app.NumbersButton.Text = 'Numbers';
            app.WordsButton = uibutton(app.Panel, 'push');
            app.WordsButton.Icon = 'icons8-scrabble-64.png';
            app.WordsButton.Text = 'Words';
            app.SentenceFormationButton = uibutton(app.Panel, 'push');
            app.SentenceFormationButton.Text = {'Sentence Formation'; ''};
            app.CustomButton = uibutton(app.Panel, 'push');
            app.CustomButton.Icon = 'icons8-random-64.png';
            app.CustomButton.Text = 'Custom';
            app.ChoosewhattodetectLabel = uilabel(app.NVRSDetectUIFigure);
            app.ChoosewhattodetectLabel.Text = 'Choose what to detect';
            app.ContinueButton = uibutton(app.NVRSDetectUIFigure, 'push');
            app.ContinueButton.Text = 'Continue';
            app.GotoSentenceFormationButton = uibutton(app.NVRSDetectUIFigure, 'push');
            app.GotoSentenceFormationButton.Text = 'Go to Sentence Formation';
            app.Image2 = uiimage(app.NVRSDetectUIFigure);
            app.Image2.ImageSource = 'coeng.png';
            app.Panel_2 = uipanel(app.NVRSDetectUIFigure);
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_2);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_2);
            app.NonverbalRecognitionSystemLabel.Text = {'Non-verbal'; 'Recognition System'};
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.Image_2 = uiimage(app.Panel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.MainMenuButton = uibutton(app.Panel_2, 'push');
            app.MainMenuButton.Text = 'Main Menu';
            app.SearchGesturesButton = uibutton(app.Panel_2, 'push');
            app.SearchGesturesButton.Icon = 'icons8-training-64.png';
            app.SearchGesturesButton.Text = 'Search Gestures';
            app.DetectDropDownLabel = uilabel(app.NVRSDetectUIFigure);
            app.DetectDropDownLabel.Text = 'Detect';
            app.DetectDropDown = uidropdown(app.NVRSDetectUIFigure);
            app.DetectDropDown.Items = {'Words', 'Letters', 'Numbers', 'Custom trained'};
            app.DetectDropDown.Value = 'Words';
            app.Panel_3 = uipanel(app.NVRSDetectUIFigure);
            app.LettersButton_2 = uibutton(app.Panel_3, 'push');
            app.LettersButton_2.Icon = 'icons8-abc-64.png';
            app.LettersButton_2.Text = 'Letters';
            app.NumbersButton_2 = uibutton(app.Panel_3, 'push');
            app.NumbersButton_2.Icon = 'icons8-cube-numbers-64.png';
            app.NumbersButton_2.Text = 'Numbers';
            app.WordsButton_2 = uibutton(app.Panel_3, 'push');
            app.WordsButton_2.Icon = 'icons8-scrabble-64.png';
            app.WordsButton_2.Text = 'Words';
            app.SentenceFormationButton_2 = uibutton(app.Panel_3, 'push');
            app.SentenceFormationButton_2.Text = {'Sentence Formation'; ''};
            app.CustomButton_2 = uibutton(app.Panel_3, 'push');
            app.CustomButton_2.Icon = 'icons8-random-64.png';
            app.CustomButton_2.Text = 'Custom';
            app.Image2_2 = uiimage(app.NVRSDetectUIFigure);
            app.Image2_2.ImageSource = 'coeng.png';
            app.Panel_4 = uipanel(app.NVRSDetectUIFigure);
            app.LogoutButton = uibutton(app.Panel_4, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.DetectGesturesLabel = uilabel(app.Panel_4);
            app.DetectGesturesLabel.Text = 'Detect Gestures';
            app.MainMenuButton_2 = uibutton(app.Panel_4, 'push');
            app.MainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_2Pushed, true);
            app.MainMenuButton_2.Text = 'Main Menu';
            app.ContinueButton_2 = uibutton(app.Panel_4, 'push');
            app.ContinueButton_2.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_2Pushed, true);
            app.ContinueButton_2.Text = 'Continue';
            app.DetectDropDown_2Label = uilabel(app.Panel_4);
            app.DetectDropDown_2Label.Text = 'Detect';
            app.DetectDropDown_2 = uidropdown(app.Panel_4);
            app.DetectDropDown_2.Items = {'Words', 'Letters', 'Numbers', 'Custom trained'};
            app.DetectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DetectDropDown_2ValueChanged, true);
            app.DetectDropDown_2.Value = 'Words';
            app.GotoSentenceFormationButton_2 = uibutton(app.Panel_4, 'push');
            app.GotoSentenceFormationButton_2.ButtonPushedFcn = createCallbackFcn(app, @GotoSentenceFormationButton_2Pushed, true);
            app.GotoSentenceFormationButton_2.Text = 'Go to Sentence Formation';
            app.Image3 = uiimage(app.NVRSDetectUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '331413091_577097417633473_8017065334155093836_n.jpg');
            app.Image_3 = uiimage(app.NVRSDetectUIFigure);
            app.Image_3.ImageSource = '11-removebg-preview.png';
            app.NonverbalRecognitionSystemLabel_2 = uilabel(app.NVRSDetectUIFigure);
            app.NonverbalRecognitionSystemLabel_2.Text = {'Non-verbal'; 'Recognition System'};
            app.NurturingTomorrowsNoblestLabel = uilabel(app.NVRSDetectUIFigure);
            app.NurturingTomorrowsNoblestLabel.Text = '"Nurturing Tomorrow''s Noblest"';
            % Show the figure after all components are created
            app.NVRSDetectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_DetectMainforUser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRSDetectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRSDetectUIFigure)
        end
    end
end