classdef NVRS_DetectforUser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRSDetectUIFigure            matlab.ui.Figure
        Image3                        matlab.ui.control.Image
        SentenceformedLabel           matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        ScoreTextArea                 matlab.ui.control.TextArea
        ScoreTextAreaLabel            matlab.ui.control.Label
        DetectedTextTextArea          matlab.ui.control.TextArea
        DetectedTextTextAreaLabel     matlab.ui.control.Label
        ChoosewhattodotodetectedgesturesLabel  matlab.ui.control.Label
        SpaceButton                   matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        MainMenuButton                matlab.ui.control.Button
        Panel_4                       matlab.ui.container.Panel
        Panel_3                       matlab.ui.container.Panel
        MainMenuButton_2              matlab.ui.control.Button
        GotoDetectButton              matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        DetectDropDown                matlab.ui.control.DropDown
        DetectDropDownLabel           matlab.ui.control.Label
        ContinueButton                matlab.ui.control.Button
        ChoosewhattodetectLabel       matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        Panel                         matlab.ui.container.Panel
        CustomButton                  matlab.ui.control.Button
        DetectButton                  matlab.ui.control.Button
        DETECTLabel                   matlab.ui.control.Label
        WordsButton                   matlab.ui.control.Button
        NumbersButton                 matlab.ui.control.Button
        LettersButton                 matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             movegui(app.NVRSDetectUIFigure,'center')
             app.StopButton.Visible = false;
        end

        % Button pushed function: MainMenuButton_2
        function MainMenuButton_2Pushed(app, event)
            app.delete
            NVRS_MainforUser
        end

        % Button pushed function: GotoDetectButton
        function GotoDetectButtonPushed(app, event)
            app.delete
            NVRS_DetectMainforUser
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Image3.Visible=true;
            app.UIAxes.Visible=false;
            app.StopButton.Visible = false;
            app.ContinueButton.Visible = true;
            global whileloop
            whileloop = false;
              msgbox("Operation Completed. STOP");
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
             app.UIAxes.Visible = true;
            app.Image3.Visible = false;
            app.ContinueButton.Visible = false;
            app.StopButton.Visible = true;
            value = app.DetectDropDown.Value;
       
        if strcmpi(value,'Words')
  x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;
    load ('Words_Images.mat','Words_Images');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
label=classify(Words_Images,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
pause(0.1)

end
            elseif strcmpi(value,'Letters')
          x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;
load ('ToTrainLetters.mat','ToTrainLetters');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
[label,score]=classify(ToTrainLetters,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
app.ScoreTextArea.Value = string(score);
pause(0.1)

end

            elseif strcmpi(value,'Numbers')
                       x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;
    load ('ToTrainNumbers.mat','ToTrainNumbers');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
[label,score]=classify(ToTrainNumbers,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label);
app.ScoreTextArea.Value = string(score);
pause(0.1)
end
            else 
        x=500;
y=250;
height=800;
width=650;
bboxes=[x y height width ];
    global label; 
    global whileloop
    c = webcam;

load ('customtrained.mat','customtrained');
whileloop = true;
while whileloop
e=snapshot(c);
es=imcrop(e,bboxes);
es=imresize(es,[227 227]);
[label,score]=classify(customtrained,es);
IFaces = insertObjectAnnotation(e,'rectangle',bboxes,label,'FontSize',30,'Color',{'cyan'},'TextColor','black');   
imshow(IFaces,'parent',app.UIAxes);
app.DetectedTextTextArea.Value = convertCharsToStrings(label) ;
app.ScoreTextArea.Value = string(score);
pause(0.1)
end
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            dttext = convertCharsToStrings(app.DetectedTextTextArea.Value);
  app.TextArea.Value = app.TextArea.Value + dttext;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
             dttext = '';
            app.TextArea.Value = dttext;
       msgbox("Operation Completed. SUCCESSFULLY DELETED");
        end

        % Button pushed function: SpaceButton
        function SpaceButtonPushed(app, event)
             tinapay = app.TextArea.Value + " ";
            app.TextArea.Value = tinapay;
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                              answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
          
          
        end

        % Close request function: NVRSDetectUIFigure
        function NVRSDetectUIFigureCloseRequest(app, event)
              YN = uiconfirm(app.NVRSDetectUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRSDetectUIFigure = uifigure('Visible', 'off');
            app.NVRSDetectUIFigure.Name = 'NVRS Detect';
            app.NVRSDetectUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRSDetectUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.NVRSDetectUIFigure);
            app.UIAxes.ALim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.NVRSDetectUIFigure);
            app.LettersButton = uibutton(app.Panel, 'push');
            app.LettersButton.Icon = 'icons8-abc-64.png';
            app.LettersButton.Text = 'Letters';
            app.NumbersButton = uibutton(app.Panel, 'push');
            app.NumbersButton.Icon = 'icons8-cube-numbers-64.png';
            app.NumbersButton.Text = 'Numbers';
            app.WordsButton = uibutton(app.Panel, 'push');
            app.WordsButton.Icon = 'icons8-scrabble-64.png';
            app.WordsButton.Text = 'Words';
            app.DETECTLabel = uilabel(app.Panel);
            app.DETECTLabel.Text = 'DETECT';
            app.DetectButton = uibutton(app.Panel, 'push');
            app.DetectButton.Icon = 'icons8-go-back-48.png';
            app.DetectButton.Text = 'Detect';
            app.CustomButton = uibutton(app.Panel, 'push');
            app.CustomButton.Text = 'Custom';
            app.Panel_2 = uipanel(app.NVRSDetectUIFigure);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.Image2 = uiimage(app.Panel_2);
            app.Image2.ImageSource = 'coeng.png';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_2);
            app.NonverbalRecognitionSystemLabel.Text = 'Non-verbal Recognition System';
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_2);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.LogoutButton = uibutton(app.Panel_2, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.Panel_3 = uipanel(app.NVRSDetectUIFigure);
            app.ChoosewhattodetectLabel = uilabel(app.Panel_3);
            app.ChoosewhattodetectLabel.Text = 'Choose what to detect';
            app.ContinueButton = uibutton(app.Panel_3, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.DetectDropDownLabel = uilabel(app.Panel_3);
            app.DetectDropDownLabel.Text = 'Detect';
            app.DetectDropDown = uidropdown(app.Panel_3);
            app.DetectDropDown.Items = {'Words', 'Letters', 'Numbers', 'Custom trained'};
            app.DetectDropDown.Value = 'Words';
            app.StopButton = uibutton(app.Panel_3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GotoDetectButton = uibutton(app.Panel_3, 'push');
            app.GotoDetectButton.ButtonPushedFcn = createCallbackFcn(app, @GotoDetectButtonPushed, true);
            app.GotoDetectButton.Text = 'Go to Detect';
            app.MainMenuButton_2 = uibutton(app.Panel_3, 'push');
            app.MainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButton_2Pushed, true);
            app.MainMenuButton_2.Text = 'Main Menu';
            app.Panel_4 = uipanel(app.NVRSDetectUIFigure);
            app.MainMenuButton = uibutton(app.NVRSDetectUIFigure, 'push');
            app.MainMenuButton.Text = 'Main Menu';
            app.TextArea = uitextarea(app.NVRSDetectUIFigure);
            app.Panel_5 = uipanel(app.NVRSDetectUIFigure);
            app.AddButton = uibutton(app.Panel_5, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.Panel_5, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SpaceButton = uibutton(app.Panel_5, 'push');
            app.SpaceButton.ButtonPushedFcn = createCallbackFcn(app, @SpaceButtonPushed, true);
            app.SpaceButton.Text = 'Space';
            app.ChoosewhattodotodetectedgesturesLabel = uilabel(app.Panel_5);
            app.ChoosewhattodotodetectedgesturesLabel.Text = 'Choose what to do to detected gestures';
            app.DetectedTextTextAreaLabel = uilabel(app.Panel_5);
            app.DetectedTextTextAreaLabel.Text = 'Detected Text:';
            app.DetectedTextTextArea = uitextarea(app.Panel_5);
            app.ScoreTextAreaLabel = uilabel(app.Panel_5);
            app.ScoreTextAreaLabel.Text = 'Score';
            app.ScoreTextArea = uitextarea(app.Panel_5);
            app.SentenceformedLabel = uilabel(app.NVRSDetectUIFigure);
            app.SentenceformedLabel.Text = 'Sentence formed';
            app.Image3 = uiimage(app.NVRSDetectUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'avatar-1577909_1280.jpeg');
            % Show the figure after all components are created
            app.NVRSDetectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_DetectforUser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRSDetectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRSDetectUIFigure)
        end
    end
end