classdef NVRS_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_MainUIFigure             matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        AccountsButton                matlab.ui.control.Button
        LogoutButton                  matlab.ui.control.Button
        TRAINButton                   matlab.ui.control.Button
        CAPTUREButton                 matlab.ui.control.Button
        DETECTButton                  matlab.ui.control.Button
        SEARCHButton                  matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        AntipoloCampusLabel           matlab.ui.control.Label
        UniversityofRizalSystemLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           movegui(app.NVRS_MainUIFigure,'center')
        end

        % Button pushed function: SEARCHButton
        function SEARCHButtonPushed(app, event)
            app.delete
            NVRS_Search
        end

        % Button pushed function: DETECTButton
        function DETECTButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Button pushed function: TRAINButton
        function TRAINButtonPushed(app, event)
            app.delete
            NVRS_Train
        end

        % Button pushed function: CAPTUREButton
        function CAPTUREButtonPushed(app, event)
            app.delete
            NVRS_Capture
        end

        % Close request function: NVRS_MainUIFigure
        function NVRS_MainUIFigureCloseRequest(app, event)
           
                        YN = uiconfirm(app.NVRS_MainUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                         answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
          
          
        end

        % Button pushed function: AccountsButton
        function AccountsButtonPushed(app, event)
            app.delete
            NVRS_Accounts
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_MainUIFigure = uifigure('Visible', 'off');
            app.NVRS_MainUIFigure.Name = 'NVRS_Main';
            app.NVRS_MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRS_MainUIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.NVRS_MainUIFigure);
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_2);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.AntipoloCampusLabel = uilabel(app.Panel_2);
            app.AntipoloCampusLabel.Text = 'Antipolo Campus';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_2);
            app.NonverbalRecognitionSystemLabel.Text = {'Non-verbal'; 'Recognition System'};
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.Image_2 = uiimage(app.Panel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.SEARCHButton = uibutton(app.Panel_2, 'push');
            app.SEARCHButton.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButtonPushed, true);
            app.SEARCHButton.Icon = 'icons8-search-64.png';
            app.SEARCHButton.Text = 'SEARCH';
            app.DETECTButton = uibutton(app.Panel_2, 'push');
            app.DETECTButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTButtonPushed, true);
            app.DETECTButton.Icon = 'icons8-sensor-64.png';
            app.DETECTButton.Text = 'DETECT';
            app.CAPTUREButton = uibutton(app.Panel_2, 'push');
            app.CAPTUREButton.ButtonPushedFcn = createCallbackFcn(app, @CAPTUREButtonPushed, true);
            app.CAPTUREButton.Icon = 'icons8-screen-64.png';
            app.CAPTUREButton.Text = 'CAPTURE';
            app.TRAINButton = uibutton(app.Panel_2, 'push');
            app.TRAINButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINButtonPushed, true);
            app.TRAINButton.Icon = 'icons8-training-64.png';
            app.TRAINButton.Text = 'TRAIN';
            app.LogoutButton = uibutton(app.Panel_2, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.AccountsButton = uibutton(app.Panel_2, 'push');
            app.AccountsButton.ButtonPushedFcn = createCallbackFcn(app, @AccountsButtonPushed, true);
            app.AccountsButton.Icon = fullfile(pathToMLAPP, 'avatar-1577909_1280.jpeg');
            app.AccountsButton.Text = 'Accounts';
            % Show the figure after all components are created
            app.NVRS_MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_MainUIFigure)
        end
    end
end