classdef NVRS_MainforUser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRSMainUIFigure              matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        DETECTButton                  matlab.ui.control.Button
        SEARCHButton                  matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        AntipoloCampusLabel           matlab.ui.control.Label
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              movegui(app.NVRSMainUIFigure,'center')
        end

        % Button pushed function: SEARCHButton
        function SEARCHButtonPushed(app, event)
            app.delete
            NVRS_SearchforUser
        end

        % Button pushed function: DETECTButton
        function DETECTButtonPushed(app, event)
            app.delete
            NVRS_DetectMainforUser
        end

        % Callback function
        function ButtonPushed(app, event)

            
        end

        % Close request function: NVRSMainUIFigure
        function NVRSMainUIFigureCloseRequest(app, event)
           
              YN = uiconfirm(app.NVRS_DetectUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
          
        end

        % Callback function
        function LogoutButton_2Pushed(app, event)
            app.delete
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRSMainUIFigure = uifigure('Visible', 'off');
            app.NVRSMainUIFigure.Name = 'NVRS Main';
            app.NVRSMainUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRSMainUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.NVRSMainUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.NVRSMainUIFigure);
            app.UniversityofRizalSystemLabel = uilabel(app.Panel);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.AntipoloCampusLabel = uilabel(app.Panel);
            app.AntipoloCampusLabel.Text = 'Antipolo Campus';
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel);
            app.NonverbalRecognitionSystemLabel.Text = {'Non-verbal'; 'Recognition System'};
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.SEARCHButton = uibutton(app.Panel, 'push');
            app.SEARCHButton.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButtonPushed, true);
            app.SEARCHButton.Icon = 'icons8-search-64.png';
            app.SEARCHButton.Text = 'SEARCH';
            app.DETECTButton = uibutton(app.Panel, 'push');
            app.DETECTButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTButtonPushed, true);
            app.DETECTButton.Icon = 'icons8-sensor-64.png';
            app.DETECTButton.Text = 'DETECT';
            app.LogoutButton = uibutton(app.Panel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            % Show the figure after all components are created
            app.NVRSMainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_MainforUser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRSMainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRSMainUIFigure)
        end
    end
end