classdef NVRS_Search < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_SearchUIFigure           matlab.ui.Figure
        Panel_8                       matlab.ui.container.Panel
        MainMenuButton                matlab.ui.control.Button
        FilterImagesLabel             matlab.ui.control.Label
        ContinueButton                matlab.ui.control.Button
        SetpictureasDropDown          matlab.ui.control.DropDown
        SetpictureasDropDownLabel     matlab.ui.control.Label
        SearchButton                  matlab.ui.control.Button
        SearchLabel                   matlab.ui.control.Label
        SearchGesturesLabel           matlab.ui.control.Label
        FilterSearchDropDown          matlab.ui.control.DropDown
        FilterSearchDropDownLabel     matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Panel_7                       matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        DetectButton                  matlab.ui.control.Button
        MainButton                    matlab.ui.control.Button
        TrainButton                   matlab.ui.control.Button
        CaptureButton                 matlab.ui.control.Button
        SetImageasButtonGroup         matlab.ui.container.ButtonGroup
        BinaryButton                  matlab.ui.control.RadioButton
        BWButton                      matlab.ui.control.RadioButton
        NormalButton                  matlab.ui.control.RadioButton
        Panel_6                       matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
    end

    
    properties (Access = private)
         webcamObject;
 imageObject;
    end
    
    properties (Access = public)
       
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           cd(fileparts(which('NVRS_Search.mlapp')));
         movegui(app.NVRS_SearchUIFigure,'center')
        end

        % Callback function
        function SearchButtonPushed(app, event)
app.SetpictureasDropDown.Value = app.SetpictureasDropDown.Items(1);
            
            cd(fileparts(which('NVRS_Search.mlapp')));
try
    

  value = app.FilterSearchDropDown.Value;
            if strcmpi(value,'All')
                fullpathname = ['ASL\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Letters')
                  fullpathname = ['ASL\Letters\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Words')
                  fullpathname = ['ASL\Words\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            else 
                fullpathname = ['ASL\Numbers\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            end


catch ME
    str = sprintf('The word you search isn''t in the database! ');
    uialert(app.NVRS_SearchUIFigure, str, 'Error!');
end


        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            app.delete
            NVRS_Capture
            
            
        end

        % Size changed function: NVRS_SearchUIFigure
        function NVRS_SearchUIFigureSizeChanged(app, event)

            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.delete
            NVRS_Train
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Selection changed function: SetImageasButtonGroup
        function SetImageasButtonGroupSelectionChanged(app, event)
try
            

            fullpathname = ['ASL\',app.EditField.Value,'.bmp'];
           img = imread (fullpathname) ;
           switch app.SetImageasButtonGroup.SelectedObject.Text
                 
    case 'Normal' %where "Button1" is the name of the first radio button
           
           
            imshow (img, 'parent', app.UIAxes) ;
    case 'B&W'
           grayImage = rgb2gray(img);
            imshow(grayImage,'parent',app.UIAxes);
    otherwise
                     grayImage = rgb2gray(img);
            meanVal = mean(grayImage,"all");
            binaryimg = grayImage >= meanVal;
            imshow(binaryimg,'parent',app.UIAxes);
           end
catch ME
    str = sprintf([ME.message]);
    uialert(app.NVRS_SearchUIFigure, str, 'Error');
end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)


           switch app.ButtonGroupSelectionChanged.SelectedObject.Text
                 
    case 'A'
           
           
            fullpathname = ['A','.bmp'];
           img = imread (fullpathname) ;
            imshow(img,'parent', app.UIAxes)
            app.EditField.Value = 'A';
    case 'B'
                  fullpathname = ['B','.bmp'];
           img = imread (fullpathname) ;
            imshow(img,'parent', app.UIAxes)
            app.EditField.Value = 'B';
    case 'C'
                    fullpathname = ['C','.bmp'];
           img = imread (fullpathname) ;
            imshow(img,'parent', app.UIAxes)
            app.EditField.Value = 'C';
         
  
    otherwise
                     grayImage = rgb2gray(img);
            meanVal = mean(grayImage,"all");
            binaryimg = grayImage >= meanVal;
            imshow(binaryimg,'parent',app.UIAxes);
           end
           
            
        end

        % Close request function: NVRS_SearchUIFigure
        function NVRS_SearchUIFigureCloseRequest(app, event)
                    YN = uiconfirm(app.NVRS_SearchUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            try
            fullpathname = ['ASL\',app.EditField.Value,'.bmp'];
           img = imread (fullpathname) ;
            value = app.SetpictureasDropDown.Value;
            if strcmpi(value,'Normal')
                imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Black and White')
                 grayImage = rgb2gray(img);
          imshow(grayImage, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Binary')
                    grayImage = rgb2gray(img);
            meanVal = mean(grayImage,"all");
            binaryimg = grayImage >= meanVal;
           imshow(binaryimg, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);

            end
            catch
            end

            
        end

        % Value changed function: SetpictureasDropDown
        function SetpictureasDropDownValueChanged(app, event)
            value = app.SetpictureasDropDown.Value;
            
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Callback function
        function ButtonPushed(app, event)
           fullpath = 'C:\Research\research';
tinapay = [fullpath,'\ASL\Letters'];
            app.TextArea.Value = ls(tinapay);

        end

        % Value changed function: FilterSearchDropDown
        function FilterSearchDropDownValueChanged(app, event)
            value = app.FilterSearchDropDown.Value;
            
        end

        % Callback function
        function TrainGesturesButtonPushed(app, event)
            app.delete
            NVRS_Train
        end

        % Callback function
        function DetectGesturesButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Callback function
        function CaptureImagesButtonPushed(app, event)
            app.delete
            NVRS_Capture
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed2(app, event)
            app.SetpictureasDropDown.Value = app.SetpictureasDropDown.Items(1);
            
            cd(fileparts(which('NVRS_Search.mlapp')));
try
    

  value = app.FilterSearchDropDown.Value;
            if strcmpi(value,'All')
                fullpathname = ['ASL\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Letters')
                  fullpathname = ['ASL\Letters\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Words')
                  fullpathname = ['ASL\Words\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            else 
                fullpathname = ['ASL\Numbers\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            end


catch ME
    str = sprintf('The word you search isn''t in the database! ');
    uialert(app.NVRS_SearchUIFigure, str, 'Error!');
end


        end

        % Callback function: not associated with a component
        function ButtonPushed2(app, event)
            
            answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_Login
        
    case 'No'
       
   
end
          

        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
             answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_SearchUIFigure = uifigure('Visible', 'off');
            app.NVRS_SearchUIFigure.Name = 'NVRS_Search';
            app.NVRS_SearchUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRS_SearchUIFigureCloseRequest, true);
            app.NVRS_SearchUIFigure.SizeChangedFcn = createCallbackFcn(app, @NVRS_SearchUIFigureSizeChanged, true);
            app.Image3 = uiimage(app.NVRS_SearchUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'students.png');
            app.Panel_6 = uipanel(app.NVRS_SearchUIFigure);
            app.Image = uiimage(app.Panel_6);
            app.Image.ImageSource = fullfile(pathToMLAPP, '11-removebg-preview.png');
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel_6);
            app.NonverbalRecognitionSystemLabel.Text = 'Non-verbal Recognition System';
            app.Image2 = uiimage(app.Panel_6);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.UniversityofRizalSystemLabel = uilabel(app.Panel_6);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.LogoutButton = uibutton(app.Panel_6, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.SetImageasButtonGroup = uibuttongroup(app.NVRS_SearchUIFigure);
            app.SetImageasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetImageasButtonGroupSelectionChanged, true);
            app.SetImageasButtonGroup.Title = 'Set Image as:';
            app.NormalButton = uiradiobutton(app.SetImageasButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.BWButton = uiradiobutton(app.SetImageasButtonGroup);
            app.BWButton.Text = 'B&W';
            app.BinaryButton = uiradiobutton(app.SetImageasButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.Panel_3 = uipanel(app.NVRS_SearchUIFigure);
            app.CaptureButton = uibutton(app.Panel_3, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Icon = 'icons8-screenshot-64.png';
            app.CaptureButton.Text = 'Capture';
            app.TrainButton = uibutton(app.Panel_3, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Icon = 'icons8-training-64 (1).png';
            app.TrainButton.Text = 'Train';
            app.MainButton = uibutton(app.Panel_3, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Icon = 'icons8-menu-64.png';
            app.MainButton.Text = 'Main';
            app.DetectButton = uibutton(app.Panel_3, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Icon = 'icons8-mayura-gesture-64.png';
            app.DetectButton.Text = 'Detect';
            app.Panel_7 = uipanel(app.NVRS_SearchUIFigure);
            app.UIAxes = uiaxes(app.Panel_7);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel_8 = uipanel(app.NVRS_SearchUIFigure);
            app.EditField = uieditfield(app.Panel_8, 'text');
            app.FilterSearchDropDownLabel = uilabel(app.Panel_8);
            app.FilterSearchDropDownLabel.Text = 'Filter Search';
            app.FilterSearchDropDown = uidropdown(app.Panel_8);
            app.FilterSearchDropDown.Items = {'All', 'Words', 'Letters', 'Numbers'};
            app.FilterSearchDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterSearchDropDownValueChanged, true);
            app.FilterSearchDropDown.Value = 'All';
            app.SearchGesturesLabel = uilabel(app.Panel_8);
            app.SearchGesturesLabel.Text = 'Search Gestures';
            app.SearchLabel = uilabel(app.Panel_8);
            app.SearchLabel.Text = 'Search ';
            app.SearchButton = uibutton(app.Panel_8, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed2, true);
            app.SearchButton.Text = 'Search';
            app.SetpictureasDropDownLabel = uilabel(app.Panel_8);
            app.SetpictureasDropDownLabel.Text = 'Set picture as';
            app.SetpictureasDropDown = uidropdown(app.Panel_8);
            app.SetpictureasDropDown.Items = {'Normal', 'Black and White', 'Binary'};
            app.SetpictureasDropDown.ValueChangedFcn = createCallbackFcn(app, @SetpictureasDropDownValueChanged, true);
            app.SetpictureasDropDown.Value = 'Normal';
            app.ContinueButton = uibutton(app.Panel_8, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.FilterImagesLabel = uilabel(app.Panel_8);
            app.FilterImagesLabel.Text = 'Filter Images';
            app.MainMenuButton = uibutton(app.Panel_8, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            % Show the figure after all components are created
            app.NVRS_SearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_Search

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_SearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_SearchUIFigure)
        end
    end
end