classdef NVRS_SearchforUser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRSSearchUIFigure            matlab.ui.Figure
        Panel_4                       matlab.ui.container.Panel
        MainMenuButton                matlab.ui.control.Button
        FilterImagesLabel             matlab.ui.control.Label
        ContinueButton                matlab.ui.control.Button
        SetpictureasDropDown          matlab.ui.control.DropDown
        SetpictureasDropDownLabel     matlab.ui.control.Label
        SearchButton                  matlab.ui.control.Button
        SearchLabel                   matlab.ui.control.Label
        SearchGesturesLabel           matlab.ui.control.Label
        FilterSearchDropDown          matlab.ui.control.DropDown
        FilterSearchDropDownLabel     matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Panel_3                       matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        SetImageasButtonGroup         matlab.ui.container.ButtonGroup
        BinaryButton                  matlab.ui.control.RadioButton
        BWButton                      matlab.ui.control.RadioButton
        NormalButton                  matlab.ui.control.RadioButton
        Panel                         matlab.ui.container.Panel
        LogoutButton                  matlab.ui.control.Button
        UniversityofRizalSystemLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
               movegui(app.NVRSSearchUIFigure,'center')
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            app.delete
            NVRS_MainforUser
        end

        % Value changed function: FilterSearchDropDown
        function FilterSearchDropDownValueChanged(app, event)
            value = app.FilterSearchDropDown.Value;
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
             app.SetpictureasDropDown.Value = app.SetpictureasDropDown.Items(1);
            
            cd(fileparts(which('NVRS_Search.mlapp')));
try
    

  value = app.FilterSearchDropDown.Value;
            if strcmpi(value,'All')
                fullpathname = ['ASL\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Letters')
                  fullpathname = ['ASL\Letters\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Words')
                  fullpathname = ['ASL\Words\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            else 
                fullpathname = ['ASL\Numbers\',app.EditField.Value,'.bmp'];
    img = imread (fullpathname) ;
    
  
   imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            end


catch ME
    str = sprintf('The word you search isn''t in the database! ');
    uialert(app.NVRSSearchUIFigure, str, 'Error!');
end


        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
             try
            fullpathname = ['ASL\',app.EditField.Value,'.bmp'];
           img = imread (fullpathname) ;
            value = app.SetpictureasDropDown.Value;
            if strcmpi(value,'Normal')
                imshow(img, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Black and White')
                 grayImage = rgb2gray(img);
          imshow(grayImage, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            elseif strcmpi(value,'Binary')
                    grayImage = rgb2gray(img);
            meanVal = mean(grayImage,"all");
            binaryimg = grayImage >= meanVal;
           imshow(binaryimg, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);

            end
            catch
            end

        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
                                 answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end
          
          
        end

        % Close request function: NVRSSearchUIFigure
        function NVRSSearchUIFigureCloseRequest(app, event)
             YN = uiconfirm(app.NVRSSearchUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRSSearchUIFigure = uifigure('Visible', 'off');
            app.NVRSSearchUIFigure.Name = 'NVRS Search';
            app.NVRSSearchUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRSSearchUIFigureCloseRequest, true);
            app.Image3 = uiimage(app.NVRSSearchUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '331413091_577097417633473_8017065334155093836_n.jpg');
            app.Panel = uipanel(app.NVRSSearchUIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '11-removebg-preview.png');
            app.NonverbalRecognitionSystemLabel = uilabel(app.Panel);
            app.NonverbalRecognitionSystemLabel.Text = 'Non-verbal Recognition System';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'coeng.png');
            app.UniversityofRizalSystemLabel = uilabel(app.Panel);
            app.UniversityofRizalSystemLabel.Text = 'University of Rizal System';
            app.LogoutButton = uibutton(app.Panel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.SetImageasButtonGroup = uibuttongroup(app.NVRSSearchUIFigure);
            app.SetImageasButtonGroup.Title = 'Set Image as:';
            app.NormalButton = uiradiobutton(app.SetImageasButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.BWButton = uiradiobutton(app.SetImageasButtonGroup);
            app.BWButton.Text = 'B&W';
            app.BinaryButton = uiradiobutton(app.SetImageasButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.Panel_3 = uipanel(app.NVRSSearchUIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel_4 = uipanel(app.NVRSSearchUIFigure);
            app.EditField = uieditfield(app.Panel_4, 'text');
            app.FilterSearchDropDownLabel = uilabel(app.Panel_4);
            app.FilterSearchDropDownLabel.Text = 'Filter Search';
            app.FilterSearchDropDown = uidropdown(app.Panel_4);
            app.FilterSearchDropDown.Items = {'All', 'Words', 'Letters', 'Numbers'};
            app.FilterSearchDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterSearchDropDownValueChanged, true);
            app.FilterSearchDropDown.Value = 'All';
            app.SearchGesturesLabel = uilabel(app.Panel_4);
            app.SearchGesturesLabel.Text = 'Search Gestures';
            app.SearchLabel = uilabel(app.Panel_4);
            app.SearchLabel.Text = 'Search ';
            app.SearchButton = uibutton(app.Panel_4, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.SetpictureasDropDownLabel = uilabel(app.Panel_4);
            app.SetpictureasDropDownLabel.Text = 'Set picture as';
            app.SetpictureasDropDown = uidropdown(app.Panel_4);
            app.SetpictureasDropDown.Items = {'Normal', 'Black and White', 'Binary'};
            app.SetpictureasDropDown.Value = 'Normal';
            app.ContinueButton = uibutton(app.Panel_4, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.FilterImagesLabel = uilabel(app.Panel_4);
            app.FilterImagesLabel.Text = 'Filter Images';
            app.MainMenuButton = uibutton(app.Panel_4, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            % Show the figure after all components are created
            app.NVRSSearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_SearchforUser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRSSearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRSSearchUIFigure)
        end
    end
end