classdef NVRS_Train < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_TrainUIFigure       matlab.ui.Figure
        Panel_6                  matlab.ui.container.Panel
        WhatgesturetotrainLabel  matlab.ui.control.Label
        TraininDropDown          matlab.ui.control.DropDown
        TraininDropDownLabel     matlab.ui.control.Label
        StartTrainingButton      matlab.ui.control.Button
        Panel_7                  matlab.ui.container.Panel
        SelectfolderyouwanttotrainLabel  matlab.ui.control.Label
        FileNameLabel            matlab.ui.control.Label
        SelectFolderButton       matlab.ui.control.Button
        FolderNameEditField      matlab.ui.control.EditField
        TrainGesturesLabel       matlab.ui.control.Label
        TraininCustomButton      matlab.ui.control.Button
        Image2                   matlab.ui.control.Image
        TraininWordsButton       matlab.ui.control.Button
        TraininNumbersButton     matlab.ui.control.Button
        TraininLettersButton     matlab.ui.control.Button
        noice                    matlab.ui.container.Panel
        LogoutButton             matlab.ui.control.Button
        MainMenuButton           matlab.ui.control.Button
        NonverbalRecognitionSystemLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Panel_4                  matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             movegui(app.NVRS_TrainUIFigure,'center')     
            cd(fileparts(which('NVRS_Train.mlapp')));
        end

        % Callback function
        function CaptureButtonPushed(app, event)
            app.delete
            NVRS_Capture
        end

        % Callback function
        function DetectButtonPushed(app, event)
            app.delete
            NVRS_DetectMain
        end

        % Callback function
        function SearchButtonPushed(app, event)
            app.delete
            NVRS_Search
        end

        % Callback function
        function MainButtonPushed(app, event)
           app.delete
            NVRS_Main
        end

        % Button pushed function: TraininCustomButton
        function TraininCustomButtonPushed(app, event)

        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
try
            dname = uigetdir("fileparts(which('NVRS_Train.mlapp'))");
            [~,name] = fileparts(dname);
            app.FolderNameEditField.Value = name;

catch
end
        end

        % Size changed function: noice
        function NVRS_TrainUIFigureCloseRequest(app, event)
                  
        end

        % Callback function
        function LoadCustomTrainedGestureButtonPushed(app, event)
       
        end

        % Button pushed function: TraininLettersButton
        function TraininLettersButtonPushed(app, event)
          
        end

        % Button pushed function: TraininNumbersButton
        function TraininNumbersButtonPushed(app, event)
            
        end

        % Button pushed function: TraininWordsButton
        function TraininWordsButtonPushed(app, event)
                       
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
                  value = app.TraininDropDown.Value;
            if strcmpi(value,'Words')
                try
            sss = app.FolderNameEditField.Value;
            movefile(sss,'ToTrainWords\')
                catch 
                end
            
            
            try
                 msgbox('Started training')
            cd(fileparts(which('NVRS_Train.mlapp')));
imds = imageDatastore('ToTrainWords', ...
    'IncludeSubfolders',true, ...
    'LabelSource','foldernames');
[imdsTrain,imdsValidation] = splitEachLabel(imds,0.7,'randomized');
net = alexnet;
inputSize = net.Layers(1).InputSize
layersTransfer = net.Layers(1:end-3);
numClasses = numel(categories(imdsTrain.Labels))
layers = [
    layersTransfer
    fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
    softmaxLayer
    classificationLayer];
pixelRange = [-30 30];
imageAugmenter = imageDataAugmenter( ...
    'RandXReflection',true, ...
    'RandXTranslation',pixelRange, ...
    'RandYTranslation',pixelRange);
augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
    'DataAugmentation',imageAugmenter);
augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
options = trainingOptions('sgdm', ...
    'MiniBatchSize',64, ...
    'MaxEpochs',20, ...
    'InitialLearnRate',1e-4, ...
    'Shuffle','every-epoch', ...
    'ValidationData',augimdsValidation, ...
    'ValidationFrequency',3, ...
    'Verbose',false, ...
    'plots',"training-progress");

ToTrainWords = trainNetwork(augimdsTrain,layers,options);
[YPred,scores] = classify(ToTrainWords,augimdsValidation);
YValidation = imdsValidation.Labels;
accuracy = mean(YPred == YValidation);

save ToTrainWords;
catch ME
    str = sprintf([ME.message]);
    uialert(app.NVRS_SearchUIFigure, str, 'Filename cannot be empty!');
    end
            elseif strcmpi(value,'Letters')
                try
                  sss = app.FolderNameEditField.Value;
            movefile(sss,'ToTrainLetters\')
                catch
                end
            
            
            try
                 msgbox('Started training')
            cd(fileparts(which('NVRS_Train.mlapp')));
imds = imageDatastore('ToTrainLetters', ...
    'IncludeSubfolders',true, ...
    'LabelSource','foldernames');
[imdsTrain,imdsValidation] = splitEachLabel(imds,0.7,'randomized');
net = alexnet;
inputSize = net.Layers(1).InputSize
layersTransfer = net.Layers(1:end-3);
numClasses = numel(categories(imdsTrain.Labels))
layers = [
    layersTransfer
    fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
    softmaxLayer
    classificationLayer];
pixelRange = [-30 30];
imageAugmenter = imageDataAugmenter( ...
    'RandXReflection',true, ...
    'RandXTranslation',pixelRange, ...
    'RandYTranslation',pixelRange);
augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
    'DataAugmentation',imageAugmenter);
augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
options = trainingOptions('sgdm', ...
    'MiniBatchSize',64, ...
    'MaxEpochs',20, ...
    'InitialLearnRate',1e-4, ...
    'Shuffle','every-epoch', ...
    'ValidationData',augimdsValidation, ...
    'ValidationFrequency',3, ...
    'Verbose',false, ...
    'plots',"training-progress");

ToTrainLetters = trainNetwork(augimdsTrain,layers,options);
[YPred,scores] = classify(ToTrainLetters,augimdsValidation);
YValidation = imdsValidation.Labels;
accuracy = mean(YPred == YValidation);
save ToTrainLetters;
catch ME
    str = sprintf([ME.message]);
    uialert(app.NVRS_SearchUIFigure, str, 'Filename cannot be empty!');
end
            elseif strcmpi(value,'Numbers')
                try
                sss = app.FolderNameEditField.Value;
            movefile(sss,'ToTrainNumbers\')
                catch
                end
            
            try
                 msgbox('Started training')
            cd(fileparts(which('NVRS_Train.mlapp')));
imds = imageDatastore('ToTrainNumbers', ...
    'IncludeSubfolders',true, ...
    'LabelSource','foldernames');
[imdsTrain,imdsValidation] = splitEachLabel(imds,0.7,'randomized');
net = alexnet;
inputSize = net.Layers(1).InputSize
layersTransfer = net.Layers(1:end-3);
numClasses = numel(categories(imdsTrain.Labels))
layers = [
    layersTransfer
    fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
    softmaxLayer
    classificationLayer];
pixelRange = [-30 30];
imageAugmenter = imageDataAugmenter( ...
    'RandXReflection',true, ...
    'RandXTranslation',pixelRange, ...
    'RandYTranslation',pixelRange);
augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
    'DataAugmentation',imageAugmenter);
augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
options = trainingOptions('sgdm', ...
    'MiniBatchSize',64, ...
    'MaxEpochs',20, ...
    'InitialLearnRate',1e-4, ...
    'Shuffle','every-epoch', ...
    'ValidationData',augimdsValidation, ...
    'ValidationFrequency',3, ...
    'Verbose',false, ...
    'plots',"training-progress");

ToTrainNumbers = trainNetwork(augimdsTrain,layers,options);
[YPred,scores] = classify(ToTrainNumbers,augimdsValidation);
YValidation = imdsValidation.Labels;
accuracy = mean(YPred == YValidation);
save ToTrainNumbers;
catch ME
    str = sprintf([ME.message]);
    uialert(app.NVRS_SearchUIFigure, str, 'Filename cannot be empty!');
end
              
            elseif strcmpi(value,'Custom')
                try
                    sss = app.FolderNameEditField.Value;
            movefile(sss,'ToTrainCustom\')
                catch
                end
                try
            cd(fileparts(which('NVRS_Train.mlapp')));
               msgbox('Started training')
imds = imageDatastore('ToTrainCustom', ...
    'IncludeSubfolders',true, ...
    'LabelSource','foldernames');
[imdsTrain,imdsValidation] = splitEachLabel(imds,0.7,'randomized');
net = alexnet;
inputSize = net.Layers(1).InputSize
layersTransfer = net.Layers(1:end-3);
numClasses = numel(categories(imdsTrain.Labels))
layers = [
    layersTransfer
    fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
    softmaxLayer
    classificationLayer];
pixelRange = [-30 30];
imageAugmenter = imageDataAugmenter( ...
    'RandXReflection',true, ...
    'RandXTranslation',pixelRange, ...
    'RandYTranslation',pixelRange);
augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
    'DataAugmentation',imageAugmenter);
augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
options = trainingOptions('sgdm', ...
    'MiniBatchSize',64, ...
    'MaxEpochs',20, ...
    'InitialLearnRate',1e-4, ...
    'Shuffle','every-epoch', ...
    'ValidationData',augimdsValidation, ...
    'ValidationFrequency',3, ...
    'Verbose',false, ...
    'plots',"training-progress");

customtrained = trainNetwork(augimdsTrain,layers,options);
[YPred,scores] = classify(customtrained,augimdsValidation);
YValidation = imdsValidation.Labels;
accuracy = mean(YPred == YValidation);
save cusmtomtrained
catch ME
    str = sprintf([ME.message]);
    uialert(app.NVRS_TrainUIFigure, str, 'Filename cannot be empty!');
end

                
            else 
             
            end
        end

        % Value changed function: TraininDropDown
        function TraininDropDownValueChanged(app, event)
            value = app.TraininDropDown.Value;
            
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            app.delete
            NVRS_Main
        end

        % Size changed function: NVRS_TrainUIFigure
        function ButtonPushed(app, event)
   
    
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed2(app, event)
                    answer = questdlg('Are you sure you want to logout', ...
	'Logout Prompt', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
          app.delete
            NVRS_login
        
    case 'No'
       
   
end


        end

        % Close request function: NVRS_TrainUIFigure
        function NVRS_TrainUIFigureCloseRequest2(app, event)
                       YN = uiconfirm(app.NVRS_TrainUIFigure,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_TrainUIFigure = uifigure('Visible', 'off');
            app.NVRS_TrainUIFigure.Name = 'NVRS_Train';
            app.NVRS_TrainUIFigure.CloseRequestFcn = createCallbackFcn(app, @NVRS_TrainUIFigureCloseRequest2, true);
            app.NVRS_TrainUIFigure.SizeChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Panel_4 = uipanel(app.NVRS_TrainUIFigure);
            app.noice = uipanel(app.NVRS_TrainUIFigure);
            app.noice.SizeChangedFcn = createCallbackFcn(app, @NVRS_TrainUIFigureCloseRequest, true);
            app.Image = uiimage(app.noice);
            app.Image.ImageSource = '11-removebg-preview.png';
            app.NonverbalRecognitionSystemLabel = uilabel(app.noice);
            app.NonverbalRecognitionSystemLabel.Text = {'Non-verbal'; 'Recognition System'};
            app.MainMenuButton = uibutton(app.noice, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.LogoutButton = uibutton(app.noice, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed2, true);
            app.LogoutButton.Icon = fullfile(pathToMLAPP, 'output.png');
            app.LogoutButton.Text = 'Logout';
            app.TraininLettersButton = uibutton(app.NVRS_TrainUIFigure, 'push');
            app.TraininLettersButton.ButtonPushedFcn = createCallbackFcn(app, @TraininLettersButtonPushed, true);
            app.TraininLettersButton.Text = 'Train in Letters';
            app.TraininNumbersButton = uibutton(app.NVRS_TrainUIFigure, 'push');
            app.TraininNumbersButton.ButtonPushedFcn = createCallbackFcn(app, @TraininNumbersButtonPushed, true);
            app.TraininNumbersButton.Text = 'Train in Numbers';
            app.TraininWordsButton = uibutton(app.NVRS_TrainUIFigure, 'push');
            app.TraininWordsButton.ButtonPushedFcn = createCallbackFcn(app, @TraininWordsButtonPushed, true);
            app.TraininWordsButton.Text = {'Train in Words'; ''};
            app.Image2 = uiimage(app.NVRS_TrainUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '331413091_577097417633473_8017065334155093836_n.jpg');
            app.TraininCustomButton = uibutton(app.NVRS_TrainUIFigure, 'push');
            app.TraininCustomButton.ButtonPushedFcn = createCallbackFcn(app, @TraininCustomButtonPushed, true);
            app.TraininCustomButton.Text = 'Train in Custom';
            app.TrainGesturesLabel = uilabel(app.NVRS_TrainUIFigure);
            app.TrainGesturesLabel.Text = 'Train Gestures';
            app.Panel_7 = uipanel(app.NVRS_TrainUIFigure);
            app.FolderNameEditField = uieditfield(app.Panel_7, 'text');
            app.SelectFolderButton = uibutton(app.Panel_7, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = {'Select Folder'; ''};
            app.FileNameLabel = uilabel(app.Panel_7);
            app.FileNameLabel.Text = 'FileName:';
            app.SelectfolderyouwanttotrainLabel = uilabel(app.Panel_7);
            app.SelectfolderyouwanttotrainLabel.Text = 'Select folder you want to train';
            app.Panel_6 = uipanel(app.NVRS_TrainUIFigure);
            app.StartTrainingButton = uibutton(app.Panel_6, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.TraininDropDownLabel = uilabel(app.Panel_6);
            app.TraininDropDownLabel.Text = 'Train in';
            app.TraininDropDown = uidropdown(app.Panel_6);
            app.TraininDropDown.Items = {'Letters', 'Numbers', 'Words', 'Custom'};
            app.TraininDropDown.ValueChangedFcn = createCallbackFcn(app, @TraininDropDownValueChanged, true);
            app.TraininDropDown.Value = 'Letters';
            app.WhatgesturetotrainLabel = uilabel(app.Panel_6);
            app.WhatgesturetotrainLabel.Text = 'What gesture to train';
            % Show the figure after all components are created
            app.NVRS_TrainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_Train

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_TrainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_TrainUIFigure)
        end
    end
end