classdef NVRS_login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NVRS_Login                      matlab.ui.Figure
        Image                           matlab.ui.control.Image
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        LoginLabel                      matlab.ui.control.Label
        NurturingTomorrowsNoblestLabel  matlab.ui.control.Label
        NonVerbalRecognitionSytemLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        PasswordVal % Description
    end
    
    methods (Access = public)
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   movegui(app.NVRS_Login,'center')

   app.Button.Visible= false;
        end

        % Callback function: LoginButton, NVRS_Login
        function LoginButtonPushed(app, event)
           useredit= app.UsernameEditField.Value;
         passedit = app.PasswordEditField.Value;
user1 = "username";
pass1 = "password";
conn = database('login',user1,pass1);


 sqlquery = ['select * from login_db '...
             'where username = ''' useredit '''' ];


data = select(conn,sqlquery);
pass = data.password;
acclvl = data.accountlevel;


    if strcmp(pass,passedit) && strcmpi(acclvl,'user')
       app.delete
       NVRS_MainforUser
       
    elseif strcmp(pass,passedit) && strcmpi(acclvl,'admin')
        app.delete
        NVRS_Main
    elseif  isempty(useredit) && isempty(passedit)
        msgbox('Username and Password cannot be empty')
    elseif isempty(useredit)
         msgbox('Username cannot be empty')
    elseif isempty(passedit)
         msgbox('Password cannot be empty')
    else
        msgbox('The username or password you entered isn’t connected to an account.')
    end


        end

        % Callback function: NVRS_Login, PasswordEditField
        function NVRS_LoginWindowKeyPress(app, event)
          
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Key release function: NVRS_Login
        function NVRS_LoginKeyRelease(app, event)


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.PasswordEditField.FontColor = 'black';
            app.Button.Visible = true;
            app.Button_2.Visible=false;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button_2.Visible=true;
            app.Button.Visible=false;
            app.PasswordEditField.FontColor = 'white';

        end

        % Close request function: NVRS_Login
        function NVRS_LoginCloseRequest(app, event)
              YN = uiconfirm(app.NVRS_Login,'Do you want to close the app?', 'Close request');
if strcmpi(YN,'OK')
    delete(app)
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NVRS_Login = uifigure('Visible', 'off');
            app.NVRS_Login.Name = 'NVRS_Login';
            app.NVRS_Login.CloseRequestFcn = createCallbackFcn(app, @NVRS_LoginCloseRequest, true);
            app.NVRS_Login.WindowKeyPressFcn = createCallbackFcn(app, @NVRS_LoginWindowKeyPress, true);
            app.NVRS_Login.KeyPressFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.NVRS_Login.KeyReleaseFcn = createCallbackFcn(app, @NVRS_LoginKeyRelease, true);
            app.Image2 = uiimage(app.NVRS_Login);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '331413091_577097417633473_8017065334155093836_n.jpg');
            app.Panel = uipanel(app.NVRS_Login);
            app.NonVerbalRecognitionSytemLabel = uilabel(app.NVRS_Login);
            app.NonVerbalRecognitionSytemLabel.Text = {'  Non-Verbal '; ' Recognition Sytem'};
            app.NurturingTomorrowsNoblestLabel = uilabel(app.NVRS_Login);
            app.NurturingTomorrowsNoblestLabel.Text = '"Nurturing Tomorrow''s Noblest"';
            app.LoginLabel = uilabel(app.NVRS_Login);
            app.LoginLabel.Text = 'Login';
            app.UsernameEditFieldLabel = uilabel(app.NVRS_Login);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.NVRS_Login, 'text');
            app.PasswordEditFieldLabel = uilabel(app.NVRS_Login);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.NVRS_Login, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @NVRS_LoginWindowKeyPress, true);
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.NVRS_Login, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.Button = uibutton(app.NVRS_Login, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'eye.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.NVRS_Login, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'photo.png');
            app.Button_2.Text = '';
            app.Image = uiimage(app.NVRS_Login);
            app.Image.ImageSource = fullfile(pathToMLAPP, '11-removebg-preview.png');
            % Show the figure after all components are created
            app.NVRS_Login.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NVRS_login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NVRS_Login)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NVRS_Login)
        end
    end
end